classdef BaseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Base                      matlab.ui.container.Panel
        GridLayout_2              matlab.ui.container.GridLayout
        ExplorediversesubjectsacrossECEcoursesLabel  matlab.ui.control.Label
        FundumentalsofComupterEngineeringButton  matlab.ui.control.Button
        CircuitsAnalysisIIButton  matlab.ui.control.Button
        CircuitsAnalysisIButton   matlab.ui.control.Button
        ActionDropDown            matlab.ui.control.DropDown
        GetstartedwithinteractivelearningLabel  matlab.ui.control.Label
        ECEIntelegentTutoringSystemCompanionLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CircuitsAnalysisIButton
        function CircuitsAnalysisIButtonPushed(app, event)
                        action =  app.ActionDropDown.Value;
            if action == "Concept Explorer"
                run(CE_201);
            elseif action == "Dynamic Testing"
                run(DT_201);
            elseif action == "System Design"
                run();
            end
        end

        % Value changed function: ActionDropDown
        function ActionDropDownValueChanged(app, event)
     
                        value = app.ActionDropDown.Value;  % Replace with your actual dropdown name

            switch value
                case 'Select Options...'
                    app.CircuitsAnalysisIButton.Enable = 'off';
                    app.CircuitsAnalysisIIButton.Enable = 'off';
                    app.FundumentalsofComupterEngineeringButton.Enable = 'off';
                case 'Self-Assesment'
                    app.CircuitsAnalysisIButton.Enable = 'off';
                    app.CircuitsAnalysisIIButton.Enable = 'off';
                    app.FundumentalsofComupterEngineeringButton.Enable = 'off';
                    run(SA);
    
                otherwise
                    app.CircuitsAnalysisIButton.Enable = 'on';
                    app.CircuitsAnalysisIIButton.Enable = 'on';
                    app.FundumentalsofComupterEngineeringButton.Enable = 'on';
      
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Base = uipanel(app.GridLayout);
            app.GridLayout_2 = uigridlayout(app.Base);
            app.Image = uiimage(app.GridLayout_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'odu.png');
            app.ECEIntelegentTutoringSystemCompanionLabel = uilabel(app.GridLayout_2);
            app.ECEIntelegentTutoringSystemCompanionLabel.Text = 'ECE Intelegent Tutoring System Companion ';
            app.GetstartedwithinteractivelearningLabel = uilabel(app.GridLayout_2);
            app.GetstartedwithinteractivelearningLabel.Text = 'Get started with interactive learning';
            app.ActionDropDown = uidropdown(app.GridLayout_2);
            app.ActionDropDown.Items = {'Select Options...', 'Concept Explorer', 'Dynamic Testing', 'System Design', 'Self-Assesment'};
            app.ActionDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionDropDownValueChanged, true);
            app.ActionDropDown.Value = 'Select Options...';
            app.CircuitsAnalysisIButton = uibutton(app.GridLayout_2, 'push');
            app.CircuitsAnalysisIButton.ButtonPushedFcn = createCallbackFcn(app, @CircuitsAnalysisIButtonPushed, true);
            app.CircuitsAnalysisIButton.Enable = 'off';
            app.CircuitsAnalysisIButton.Text = 'Circuits Analysis I';
            app.CircuitsAnalysisIIButton = uibutton(app.GridLayout_2, 'push');
            app.CircuitsAnalysisIIButton.Enable = 'off';
            app.CircuitsAnalysisIIButton.Text = 'Circuits Analysis II';
            app.FundumentalsofComupterEngineeringButton = uibutton(app.GridLayout_2, 'push');
            app.FundumentalsofComupterEngineeringButton.Enable = 'off';
            app.FundumentalsofComupterEngineeringButton.Text = 'Fundumentals of Comupter Engineering';
            app.ExplorediversesubjectsacrossECEcoursesLabel = uilabel(app.GridLayout_2);
            app.ExplorediversesubjectsacrossECEcoursesLabel.Text = 'Explore diverse subjects across ECE courses';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BaseApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end