classdef ECE201 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ContentDisplay  matlab.ui.control.Image
        GridLayout      matlab.ui.container.GridLayout
        UIAxesCircuit   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Questions = [
            struct("question", "Identify the circuit shown as series or parallel", "image", "series.png", "answer", "series")
        ];

        gridSize = [20, 20];
        cellSize = 20;
        ComponentImages;

    end
    
    methods (Access = public)
        
    function drawGrid(app)
        cla(app.UIAxesCircuit);
        hold(app.UIAxesCircuit, "on");

        width = app.gridSize(1) * app.cellSize;
        height = app.gridSize(2) * app.cellSize;

        % Draw horizontal lines
        for y = 0:app.cellSize:height
            plot(app.UIAxesCircuit, [0 width], [y y], '--', 'Color', [0.8 0.8 0.8]);
        end

        % Draw vertical lines
        for x = 0:app.cellSize:width
            plot(app.UIAxesCircuit, [x x], [0 height], '--', 'Color', [0.8 0.8 0.8]);
        end

        % Set axes limits and orientation
        app.UIAxesCircuit.XLim = [0 width];
        app.UIAxesCircuit.YLim = [0 height];
        app.UIAxesCircuit.YDir = 'reverse';
        axis(app.UIAxesCircuit, 'equal');
        hold(app.UIAxesCircuit, "off");
    end

    function drawSingleResistor(app)
    % Grid size
    nx = 20;
    ny = 20;

    % Clear UIAxes
    cla(app.UIAxesCircuit);
    hold(app.UIAxesCircuit,'on');

    % Set axes limits
    app.UIAxesCircuit.XLim = [0 nx];
    app.UIAxesCircuit.YLim = [0 ny];
    app.UIAxesCircuit.DataAspectRatio = [1 1 1];  % square grid cells
    app.UIAxesCircuit.XTick = 0:nx;
    app.UIAxesCircuit.YTick = 0:ny;
    grid(app.UIAxesCircuit,'on');
    set(app.UIAxesCircuit, 'YDir', 'normal');

    % Load the resistor image
    [img, ~, alpha] = imread('resistor.png');

    %–––––––––––––––––––––––––––––––––––––––––––
    %   RESISTOR #1 — Center (10,10)
    %–––––––––––––––––––––––––––––––––––––––––––
    h1 = imshow(img, 'Parent', app.UIAxesCircuit, ...
        'XData', [10, 11], ...   % fill grid cell horizontally
        'YData', [10, 11]);      % fill grid cell vertically

    % Apply transparency
    if ~isempty(alpha)
        set(h1, 'AlphaData', double(alpha)/255);
    end

    %–––––––––––––––––––––––––––––––––––––––––––
    %   RESISTOR #2 — Left (9,10)
    %–––––––––––––––––––––––––––––––––––––––––––
    h2 = imshow(img, 'Parent', app.UIAxesCircuit, ...
        'XData', [9, 10], ...    % one cell left
        'YData', [10, 11]);

    if ~isempty(alpha)
        set(h2, 'AlphaData', double(alpha)/255);
    end

    end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawGrid(app);
            drawSingleResistor(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxesCircuit = uiaxes(app.GridLayout);
            title(app.UIAxesCircuit, 'Title')
            xlabel(app.UIAxesCircuit, 'X')
            ylabel(app.UIAxesCircuit, 'Y')
            zlabel(app.UIAxesCircuit, 'Z')
            app.ContentDisplay = uiimage(app.UIFigure);
            app.ContentDisplay.ImageSource = fullfile(pathToMLAPP, 'series.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE201

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end