classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CloseButton             matlab.ui.control.Button
        Panel_7                 matlab.ui.container.Panel
        CloseECEITSLabel        matlab.ui.control.Label
        ECEITSDisplayPanel      matlab.ui.container.Panel
        AIPanel                 matlab.ui.container.Panel
        Image                   matlab.ui.control.Image
        Panel_3                 matlab.ui.container.Panel
        MaterialSelectionLabel  matlab.ui.control.Label
        SubjectDropDown         matlab.ui.control.DropDown
        SubjectDropDownLabel    matlab.ui.control.Label
        ChapterDropDown         matlab.ui.control.DropDown
        ChapterDropDownLabel    matlab.ui.control.Label
        Gauge                   matlab.ui.control.Gauge
        Panel_5                 matlab.ui.container.Panel
        ProficiencyMeterLabel   matlab.ui.control.Label
        LaunchButton            matlab.ui.control.Button
        Panel_6                 matlab.ui.container.Panel
        ConfirmSelectionLabel   matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        AIToolbarLabel          matlab.ui.control.Label
        SaveChatButton          matlab.ui.control.Button
        OpenAIButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        SubjectData
        AIAppHandle
        BaseAppHandle
    end
    
    methods (Access = private)
        
        function appHandle = toggleAppEmbedding(app, appConstructor, parentPanel, currentHandle)
            
            if isempty(currentHandle) || ~isvalid(currentHandle)
                % --- EMBED / OPEN THE APP ---
                
                % Ensure the parent panel uses normalized units
                parentPanel.Units = 'normalized';
                
                % Directly call the app's constructor function with the parentPanel.
                appHandle = appConstructor(parentPanel); 
            else
                % --- CLOSE / DELETE THE APP ---
                
                delete(currentHandle);
                appHandle = []; % Clear the handle
            end
        end
        
        function updatePanel(app)

            
            % This function updates the UI panel based on selections
        
            subject = app.SubjectDropDown.Value;
            chapter = app.ChapterDropDown.Value;

            % Or add logic to display chapter-specific content here
  
        end
        
        function absPos = getAbsolutePosition(app, component)
 
            absPos = component.Position;
            parent = component.Parent;
            while ~isa(parent, 'matlab.ui.Figure')
                absPos(1:2) = absPos(1:2) + parent.Position(1:2);
                parent = parent.Parent;
            end
        end
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.UIFigure.WindowState = 'fullscreen';  % Auto-fullscreen the app
    
    app.UIFigure.Resize = 'off';  % Disable resizing
    app.AIPanel.Visible = 'off';

    app.UIFigure.MenuBar = 'none';
    app.UIFigure.ToolBar = 'none';

    app.BaseAppHandle = app.toggleAppEmbedding(@BaseApp, app.ECEITSDisplayPanel, app.BaseAppHandle);

    % Create a map for subjects
    app.SubjectData = containers.Map();

    % Chapters per subject as maps too
    app.SubjectData('Circuit Analysis') = containers.Map( ...
        {'Ohm''s Law','Kirchhoff''s Laws','Thevenin''s Theorem','AI Quiz'}, ...
        { '', '', '' ,''});

    app.SubjectData('Digital Electronics') = containers.Map( ...
        {'Logic Gates','Boolean Algebra','Flip-Flops','AI Quiz'}, ...
        { '', '', '',''});

    % Populate Subject dropdown
    app.SubjectDropDown.Items = keys(app.SubjectData);

    % Set default subject and chapters
    defaultSubject = app.SubjectDropDown.Items{1};
    app.SubjectDropDown.Value = defaultSubject;
    app.ChapterDropDown.Items = keys(app.SubjectData(defaultSubject));
    app.ChapterDropDown.Value = app.ChapterDropDown.Items{1};

    % Update panel if needed
    updatePanel(app);

        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
    
    % Get the selected subject from the Subject dropdown
    selectedSubject = app.SubjectDropDown.Value;

    % Get the chapters map for the selected subject
    chaptersMap = app.SubjectData(selectedSubject);

    % Extract the chapter names (keys) as a cell array
    chapters = keys(chaptersMap);

    % Update the Chapter dropdown with the relevant chapters
    app.ChapterDropDown.Items = chapters;

    % Set the Chapter dropdown to the first chapter by default (if available)
    if ~isempty(chapters)
        app.ChapterDropDown.Value = chapters{1};
    else
        app.ChapterDropDown.Value = '';
    end

    % Optionally update the UI panel or other components with new info
    updatePanel(app);
            
        end

        % Button pushed function: OpenAIButton
        function OpenAIButtonPushed(app, event)
    
    % Use the helper function to toggle the AI app
    % CHANGE: Use the function handle @AskAI instead of the string 'AskAI'
    app.AIAppHandle = app.toggleAppEmbedding(@AskAI, app.AIPanel, app.AIAppHandle);
    
    if ~isempty(app.AIAppHandle)
        % App was opened

        uistack(app.AIPanel, 'top');

        app.AIPanel.Visible = 'on';
        app.OpenAIButton.Text = 'Close AI';
    else
        % App was closed (handle is now empty)
        app.AIPanel.Visible = 'off';
        app.OpenAIButton.Text = 'Open AI';
    end
        end

        % Button pushed function: SaveChatButton
        function SaveChatButtonPushed(app, event)
        try
                disp("Save button pushed.");

                if isempty(app.AIAppHandle)
                    error("The AI is not open.");
                end

                chatText = app.AIAppHandle.AiResponse.Value;

                if isempty(chatText)
                    warning("No chat to save.");
                end

                % Get app folder path
                appFolder = fileparts(mfilename('fullpath'));

                % Define savedChats folder path
                savedChatsFolder = fullfile(appFolder, 'savedChats');

                % Create the folder if it doesn't exist
                if ~isfolder(savedChatsFolder)
                    mkdir(savedChatsFolder);
                end

                % Create timestamped filename inside savedChats folder
                timestamp = string(datetime('now','Format','yyyy-MM-dd_HH-mm-ss'));
                filename = fullfile(savedChatsFolder, "savedChat_" + timestamp + ".txt");

                % Save the chat text
                writelines(chatText, filename);
                disp("Saved chat to: " + filename);

                uialert(app.UIFigure, 'Chat saved successfully in savedChats folder.', 'Success');
    
        catch ME
                disp("Error:");
                disp(ME.message);
                uialert(app.UIFigure, "Failed to save: " + ME.message, 'Error');
        end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
  
        % Do Nothing

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenAIButton = uibutton(app.UIFigure, 'push');
            app.OpenAIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAIButtonPushed, true);
            app.OpenAIButton.Text = 'Open AI';
            app.SaveChatButton = uibutton(app.UIFigure, 'push');
            app.SaveChatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChatButtonPushed, true);
            app.SaveChatButton.Text = 'Save Chat';
            app.Panel_2 = uipanel(app.UIFigure);
            app.AIToolbarLabel = uilabel(app.Panel_2);
            app.AIToolbarLabel.Text = 'AI Toolbar';
            app.Panel_6 = uipanel(app.UIFigure);
            app.ConfirmSelectionLabel = uilabel(app.Panel_6);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.Text = 'Launch';
            app.Panel_5 = uipanel(app.UIFigure);
            app.ProficiencyMeterLabel = uilabel(app.Panel_5);
            app.ProficiencyMeterLabel.Text = 'Proficiency Meter';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.ChapterDropDownLabel = uilabel(app.UIFigure);
            app.ChapterDropDownLabel.Text = 'Chapter';
            app.ChapterDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.MaterialSelectionLabel = uilabel(app.Panel_3);
            app.MaterialSelectionLabel.Text = 'Material Selection';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'odu.png');
            app.ECEITSDisplayPanel = uipanel(app.UIFigure);
            app.ECEITSDisplayPanel.Title = 'ECE ITS Display Panel';
            app.AIPanel = uipanel(app.ECEITSDisplayPanel);
            app.AIPanel.Title = 'Ask AI Tutor';
            app.Panel_7 = uipanel(app.UIFigure);
            app.CloseECEITSLabel = uilabel(app.Panel_7);
            app.CloseECEITSLabel.Text = 'Close ECE ITS';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end